classdef Logapp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure               matlab.ui.Figure
        ReadLogButton          matlab.ui.control.Button
        CommandsTextArea       matlab.ui.control.TextArea
        CommandsTextAreaLabel  matlab.ui.control.Label
    end


    properties (Access = private)
        callingapp % Description
        counter = 1;
        cellArrayText = [];
    end
    %should be done using iterative method
    methods (Access = public)
        function str = getString(~,frame)
            %
            switch frame(3)
                case 0
                    str = "PING";
                case 1
                    str = "INIT";
                case 2
                    str = "ACK";
                case 3
                    str = "NACK";
                    %                 case 4
                    %                 case 5
                    %                 case 6
                    %                 case 7
                    %                 case 8
                    %                 case 9
                case 10
                    str = "Get Angles";
                case 11
                    str = "Get Gyro Data";
                case 12
                    str = "Get Magneto Data";
                case 13
                    str = "Get Temperature";
                case 14
                    str = "Get Sun Sensors Data";
                case 15
                    str = "Get Telmetry";
                case 16
                    str = "Change Motors Speed";
                case 17
                    str = "Flash Write";
                case 18
                    str = "Flash Read";
                case 19
                    str = "Flash Erase";
                case 20
                    str = "Flash Unlock";
                case 21
                    str = "Flash Lock";
                case 22
                    str = "Read Log";
                case 23
                    str = "Get Motors Speed";
                case 24
                    str = "Start Latch";
                case 25
                    str = "Stop Latch";
                    %                 case 26

                otherwise
                    str = "error";
            end
        end
%         function dispLog(app,allframes)
%             %   [frame,status] = Process(array,"in");
%             app.cellArrayText = [];
%             FENDindeces = find(allframes ==0xc0);
%             for i=2:2:length(FENDindeces)
%                 [frame,status] =  Process(allframes(FENDindeces(i-1):FENDindeces(i)),"in");
%                 if status
%                     app.cellArrayText = [app.cellArrayText sprintf(i/2+" - " +app.getString(frame))];
%                 else
%                     app.cellArrayText =[app.cellArrayText sprintf(i/2+" - " +"CRC ERROR")];
%                 end
%             end
%             app.CommandsTextArea.Value = app.cellArrayText;
%         end
        function LogCallback(app,~,~)
            array = app.callingapp.getframe(7);
            [frame,status] = Process(array,"in");
            if status
                app.cellArrayText = [app.cellArrayText sprintf((length(app.cellArrayText)+1) +" - " +app.getString(frame))];
            else
                app.cellArrayText =[app.cellArrayText sprintf((length(app.cellArrayText)+1)+" - " +"CRC ERROR")];
            end
            app.CommandsTextArea.Value = app.cellArrayText;
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapphandler)
            app.callingapp = mainapphandler;
            mainapphandler.LogButton.Enable = "off";
        end

        % Button pushed function: ReadLogButton
        function ReadLogButtonPushed(app, event)
            command = [0x07,0x01,22,0];
            app.callingapp.send(command,@app.LogCallback,7);
            app.cellArrayText = [];
            %             command = Process(command,"out");

            %             configureCallback(app.callingapp.Serialdev,"off");
            %             write(app.callingapp.Serialdev,command,"uint8");
            %             pause(0.3);
            %             allframes = read(app.callingapp.Serialdev,app.callingapp.Serialdev.NumBytesAvailable,"uint8");
            %             app.dispLog(allframes);
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            app.callingapp.LogHandle=[];
            app.callingapp.LogButton.Enable = "on";
            delete(app)

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.CommandsTextAreaLabel = uilabel(app.UIFigure);
            app.CommandsTextAreaLabel.Text = 'Commands';
            app.CommandsTextArea = uitextarea(app.UIFigure);
            app.CommandsTextArea.Editable = 'off';
            app.ReadLogButton = uibutton(app.UIFigure, 'push');
            app.ReadLogButton.ButtonPushedFcn = createCallbackFcn(app, @ReadLogButtonPushed, true);
            app.ReadLogButton.Text = 'Read Log';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Logapp(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end