classdef Magnetometerapp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure         matlab.ui.Figure
        zEditField       matlab.ui.control.EditField
        zEditFieldLabel  matlab.ui.control.Label
        yEditField       matlab.ui.control.EditField
        yEditFieldLabel  matlab.ui.control.Label
        xEditField       matlab.ui.control.EditField
        xEditFieldLabel  matlab.ui.control.Label
        Button           matlab.ui.control.Button
    end


    properties (Access = private)
        callingapp % Description
    end
    methods (Access = public)

        function dispMagneto(app,Magnetox,Magnetoy,Magnetoz)
            app.xEditField.Value=num2str(Magnetox);
            app.yEditField.Value=num2str(Magnetoy);
            app.zEditField.Value=num2str(Magnetoz);
        end
    end
    methods (Access = private)

        function MagnetoCallback(app,~,~)
            [flag, array] = app.callingapp.checkfends();
            if flag
                [frame,status] = Process(array,"in");
                if status
                    app.callingapp.PingMsgLabel.Text = reshape(dec2hex(frame,2)',1,[]);
                    x=typecast((uint8(frame( 4: 7))),'single');
                    y=typecast((uint8(frame( 8:11))),'single');
                    z=typecast((uint8(frame(12:15))),'single');
                    app.dispMagneto(x,y,z);
                end
            end
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapphandler)
            app.callingapp = mainapphandler;
            mainapphandler.MagnetometerButton.Enable = "off";
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            app.callingapp.MagnetometerButton.Enable = "on";
            delete(app)
        end

        % Button pushed function: Button
        function ButtonPushed(app, event)
            command = [0x07,0x01,12];
            command = Process(command,"out");
            configureCallback(app.callingapp.Serialdev,"terminator",@app.MagnetoCallback);
            write(app.callingapp.Serialdev,command,"uint8");
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.Button = uibutton(app.UIFigure, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.xEditFieldLabel = uilabel(app.UIFigure);
            app.xEditFieldLabel.Text = 'x';
            app.xEditField = uieditfield(app.UIFigure, 'text');
            app.yEditFieldLabel = uilabel(app.UIFigure);
            app.yEditFieldLabel.Text = 'y';
            app.yEditField = uieditfield(app.UIFigure, 'text');
            app.zEditFieldLabel = uilabel(app.UIFigure);
            app.zEditFieldLabel.Text = 'z';
            app.zEditField = uieditfield(app.UIFigure, 'text');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Magnetometerapp(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end